classdef new_comm_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_status_2              matlab.ui.control.Label
        Label_status_1              matlab.ui.control.Label
        QAMModulationButton         matlab.ui.control.Button
        SSBModulationButton         matlab.ui.control.Button
        STOPsignal1Button           matlab.ui.control.Button
        STARTsignal1Button          matlab.ui.control.Button
        STOPsignal2Button           matlab.ui.control.Button
        VoiceSpectrumAnalyzerLabel  matlab.ui.control.Label
        STARTsignal2Button          matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        FrequencyDomainPlot_2       matlab.ui.control.UIAxes
        TimeDomainPlot_2            matlab.ui.control.UIAxes
        FrequencyDomainPlot         matlab.ui.control.UIAxes
        TimeDomainPlot_1            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Recorder1
        Recorder2
        Audio1
        Audio2 % Audio recorder objects
    end
    
    methods (Access = private)
        
     function processRecording(app)
    % Retrieve audio data

    Fs = 48000; % Sampling frequency

    if isa(app.Recorder1, 'audiorecorder') && ~isrecording(app.Recorder1)
        audioSignal1 = getaudiodata(app.Recorder1);
        app.Audio1 = audioSignal1; 
        
        % plotting code% 
         
        % Time-Domain Plot
        t1 = (0:length(audioSignal1)-1) / Fs;
        plot(app.TimeDomainPlot_1, t1, audioSignal1);
        xlabel(app.TimeDomainPlot_1, 'Time (s)');
        ylabel(app.TimeDomainPlot_1, 'Amplitude');
        grid(app.TimeDomainPlot_1, 'on');

        % Frequency-Domain Analysis
        N = length(audioSignal1); % Signal length
        fftSignal = fft(audioSignal1); % Perform FFT
        frequencies = (0:N-1)*(Fs/N); % Frequency vector
        fftSignal = fftSignal(1:N/2); % Positive frequencies only
        frequencies = frequencies(1:N/2);

        % Update Frequency Plot
        plot(app.FrequencyDomainPlot, frequencies, abs(fftSignal));
        xlabel(app.FrequencyDomainPlot, 'Frequency (Hz)');
        ylabel(app.FrequencyDomainPlot, 'Amplitude');
        grid(app.FrequencyDomainPlot, 'on');

        % Enable Interactive Control
        zoom(app.FrequencyDomainPlot, 'on'); % Enable zooming
        pan(app.FrequencyDomainPlot, 'on');  % Enable panning
        app.Label_status_1.Text = 'Recording 1 Done!';

     end

        % Process Recorder2
    if isa(app.Recorder2, 'audiorecorder') && ~isrecording(app.Recorder2)
        audioSignal2 = getaudiodata(app.Recorder2);
        app.Audio2 = audioSignal2;

        % plotting code% 

        % Time-Domain Plot
        t2 = (0:length(audioSignal2)-1) / Fs;
        plot(app.TimeDomainPlot_2, t2, audioSignal2);
        xlabel(app.TimeDomainPlot_2, 'Time (s)');
        ylabel(app.TimeDomainPlot_2, 'Amplitude');
        grid(app.TimeDomainPlot_2, 'on');

        % Frequency-Domain Analysis
        N = length(audioSignal2); % Signal length
        fftSignal = fft(audioSignal2); % Perform FFT
        frequencies = (0:N-1)*(Fs/N); % Frequency vector
        fftSignal = fftSignal(1:N/2); % Positive frequencies only
        frequencies = frequencies(1:N/2);

        % Update Frequency Plot
        plot(app.FrequencyDomainPlot_2, frequencies, abs(fftSignal));
        xlabel(app.FrequencyDomainPlot_2, 'Frequency (Hz)');
        ylabel(app.FrequencyDomainPlot_2, 'Amplitude');

        grid(app.FrequencyDomainPlot_2, 'on');

        % Enable Interactive Control
        zoom(app.FrequencyDomainPlot_2, 'on'); % Enable zooming
        pan(app.FrequencyDomainPlot_2, 'on');  % Enable panning
        app.Label_status_2.Text = 'Recording 2 Done!';
    end

end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTsignal2Button
        function STARTsignal2ButtonPushed(app, event)
        Tmax = 5;
        Fs = 48000;
        app.Label_status_2.Text = 'Recording started...';
        % Create audio recorder object
        app.Recorder2 = audiorecorder(Fs, 16, 1); % 16-bit, mono
        recordblocking(app.Recorder2, Tmax); % Start and stop automatically after Tmax seconds

        % Retrieve and process audio data
        processRecording(app);
        end

        % Button pushed function: STOPsignal2Button
        function STOPsignal2ButtonPushed(app, event)
        if isrecording(app.Recorder2)
            stop(app.Recorder2); % Stop recording
        end
        % Process the recorded audio
        processRecording(app);
        end

        % Button pushed function: STARTsignal1Button
        function STARTsignal1ButtonPushed(app, event)
        Tmax = 5;
        Fs = 48000;
        app.Label_status_1.Text = 'Recording started...';
        % Create audio recorder object
        app.Recorder1 = audiorecorder(Fs, 16, 1); % 16-bit, mono
        recordblocking(app.Recorder1, Tmax); % Start and stop automatically after Tmax seconds

        % Retrieve and process audio data
        processRecording(app);
        end

        % Button pushed function: STOPsignal1Button
        function STOPsignal1ButtonPushed(app, event)
        if isrecording(app.Recorder1)
            stop(app.Recorder1); % Stop recording
        end
        % Process the recorded audio
        processRecording(app);
        end

        % Button pushed function: SSBModulationButton
        function SSBModulationButtonPushed(app, event)
% Ensure both signals are recorded
        if isempty(app.Audio1) || isempty(app.Audio2)
            uialert(app.UIFigure, 'Please record both signals first.', 'Missing Audio');
            return;
        end

        fs_original = 48000;
        fs_target = 220000;
        fc = 100000;

        % Resample
        audio1_resampled = resample(app.Audio1, fs_target, fs_original);
        audio2_resampled = resample(app.Audio2, fs_target, fs_original);

        t1 = (0:length(audio1_resampled)-1)' / fs_target;
        t2 = (0:length(audio2_resampled)-1)' / fs_target;

        f1 = linspace(-fs_target/2, fs_target/2, length(audio1_resampled));
        f2 = linspace(-fs_target/2, fs_target/2, length(audio2_resampled));

        % Hilbert transforms
        hilbert1 = imag(hilbert(audio1_resampled));
        hilbert2 = imag(hilbert(audio2_resampled));

        hilbert1f = fftshift(fft(hilbert1)/length(hilbert1));
        hilbert2f = fftshift(fft(hilbert2)/length(hilbert2));

        % Plot Hilbert transform (time & frequency)
        figure('Name', 'Hilbert Transforms');

        subplot(2,1,1); 
        plot(f1, abs(hilbert1f)); 
        title('Hilbert of Audio1 (freq)');
        xlabel('Freq'); ylabel('Mag');

        subplot(2,1,2);
        plot(f2, abs(hilbert2f)); 
        title('Hilbert of Audio2 (freq)');
        xlabel('Freq'); ylabel('Mag');

        % I/Q components
        usb1 = audio1_resampled .* cos(2*pi*fc*t1) - hilbert1 .* sin(2*pi*fc*t1);
        lsb2 = audio2_resampled .* cos(2*pi*fc*t2) + hilbert2 .* sin(2*pi*fc*t2);

        % Frequency domain of modulated
        usb1f = fftshift(fft(usb1)/length(usb1));
        lsb2f = fftshift(fft(lsb2)/length(lsb2));

        % Plot Modulated signals
        figure('Name', 'SSB Modulated Signals');
       
        subplot(2,1,1); 
        plot(f1, abs(usb1f));
        title('USB (Audio1) - Frequency');
        
        subplot(2,1,2);
        plot(f2, abs(lsb2f));
        title('LSB (Audio2) - Frequency');

        % Demodulate
        usb1_demod = lowpass(usb1 .* cos(2*pi*fc*t1), 10000, fs_target);
        lsb2_demod = lowpass(lsb2 .* cos(2*pi*fc*t2), 10000, fs_target);

        usb1_demodf = fftshift(fft(usb1_demod)/length(usb1_demod));
        lsb2_demodf = fftshift(fft(lsb2_demod)/length(lsb2_demod));

        % Plot Demodulated
        figure('Name', 'Demodulated Signals');
        subplot(2,1,1); 

        plot(f1, abs(usb1_demodf));
        title('Demodulated USB (Audio1) - Frequency');

        subplot(2,1,2); 
        plot(f2, abs(lsb2_demodf));
        title('Demodulated LSB (Audio2) - Frequency');
        end

        % Button pushed function: QAMModulationButton
        function QAMModulationButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.TimeDomainPlot_1 = uiaxes(app.Panel);
            title(app.TimeDomainPlot_1, 'M1(t) ')
            xlabel(app.TimeDomainPlot_1, 'Time (s)')
            ylabel(app.TimeDomainPlot_1, 'Amplitude')
            zlabel(app.TimeDomainPlot_1, 'Z')
            app.FrequencyDomainPlot = uiaxes(app.Panel);
            title(app.FrequencyDomainPlot, 'M1(f) ')
            xlabel(app.FrequencyDomainPlot, 'Frequancy (Hz)')
            zlabel(app.FrequencyDomainPlot, 'Z')
            app.TimeDomainPlot_2 = uiaxes(app.Panel);
            title(app.TimeDomainPlot_2, 'M2(t) ')
            xlabel(app.TimeDomainPlot_2, 'Time (s)')
            ylabel(app.TimeDomainPlot_2, 'Amplitude')
            zlabel(app.TimeDomainPlot_2, 'Z')
            app.FrequencyDomainPlot_2 = uiaxes(app.Panel);
            title(app.FrequencyDomainPlot_2, 'M2(f) ')
            xlabel(app.FrequencyDomainPlot_2, 'Frequancy (Hz)')
            zlabel(app.FrequencyDomainPlot_2, 'Z')
            app.STARTsignal2Button = uibutton(app.UIFigure, 'push');
            app.STARTsignal2Button.ButtonPushedFcn = createCallbackFcn(app, @STARTsignal2ButtonPushed, true);
            app.STARTsignal2Button.Text = 'START (signal 2)';
            app.VoiceSpectrumAnalyzerLabel = uilabel(app.UIFigure);
            app.VoiceSpectrumAnalyzerLabel.Text = 'Voice Spectrum Analyzer';
            app.STOPsignal2Button = uibutton(app.UIFigure, 'push');
            app.STOPsignal2Button.ButtonPushedFcn = createCallbackFcn(app, @STOPsignal2ButtonPushed, true);
            app.STOPsignal2Button.Text = 'STOP (signal 2)';
            app.STARTsignal1Button = uibutton(app.UIFigure, 'push');
            app.STARTsignal1Button.ButtonPushedFcn = createCallbackFcn(app, @STARTsignal1ButtonPushed, true);
            app.STARTsignal1Button.Text = 'START (signal 1)';
            app.STOPsignal1Button = uibutton(app.UIFigure, 'push');
            app.STOPsignal1Button.ButtonPushedFcn = createCallbackFcn(app, @STOPsignal1ButtonPushed, true);
            app.STOPsignal1Button.Text = 'STOP (signal 1)';
            app.SSBModulationButton = uibutton(app.UIFigure, 'push');
            app.SSBModulationButton.ButtonPushedFcn = createCallbackFcn(app, @SSBModulationButtonPushed, true);
            app.SSBModulationButton.Text = 'SSB Modulation';
            app.QAMModulationButton = uibutton(app.UIFigure, 'push');
            app.QAMModulationButton.ButtonPushedFcn = createCallbackFcn(app, @QAMModulationButtonPushed, true);
            app.QAMModulationButton.Text = 'QAM Modulation';
            app.Label_status_1 = uilabel(app.UIFigure);
            app.Label_status_1.Text = '';
            app.Label_status_2 = uilabel(app.UIFigure);
            app.Label_status_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_comm_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end